classdef OptMotor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ParametrGroup  matlab.ui.container.ButtonGroup
        Current        matlab.ui.control.RadioButton
        Speed          matlab.ui.control.RadioButton
        RegimGroup     matlab.ui.container.ButtonGroup
        Regim2         matlab.ui.control.RadioButton
        Regim1         matlab.ui.control.RadioButton
        Cancel         matlab.ui.control.Button
        Save           matlab.ui.control.Button
    end


    properties (Access = private) 
        CallingApp;
        regim=3+1;
        Parametr_index=13;
        m;
        
    end
    
    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, matlab)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.m=matlab;
            % Update UI with input values
            
            
        end

        % Button pushed function: Save
        function SavePushed(app, event)
            % Call main app's public function
            app.CallingApp.Start.Enable = 'on';
            app.CallingApp.Stop.Enable = 'on';
            app.CallingApp.SwitchConnect.Enable= 'on';
            app.CallingApp.OptionsMotor.Enable = 'on';
            app.CallingApp.SpeedMotor.Enable = 'on';
            app.CallingApp.StopMotor.Enable = 'on';
            app.CallingApp.StartMotor.Enable = 'on';
            app.CallingApp.ValueGroup.Enable= 'on';
            app.CallingApp.PlotGo.Enable= 'on';
            write(app.m,'coils',app.regim,(1)); 
            write(app.m,'coils',app.Parametr_index,(1)); 
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.Start.Enable = 'on';
            app.CallingApp.Stop.Enable = 'on';
            app.CallingApp.SwitchConnect.Enable= 'on';
            app.CallingApp.OptionsMotor.Enable = 'on';
            app.CallingApp.SpeedMotor.Enable = 'on';
            app.CallingApp.StopMotor.Enable = 'on';
            app.CallingApp.StartMotor.Enable = 'on';
            app.CallingApp.ValueGroup.Enable= 'on';
            app.CallingApp.PlotGo.Enable= 'on';
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.Start.Enable = 'on';
            app.CallingApp.Stop.Enable = 'on';
            app.CallingApp.SwitchConnect.Enable= 'on';
            app.CallingApp.OptionsMotor.Enable = 'on';
            app.CallingApp.SpeedMotor.Enable = 'on';
            app.CallingApp.StopMotor.Enable = 'on';
            app.CallingApp.StartMotor.Enable = 'on';
            app.CallingApp.ValueGroup.Enable= 'on';
            app.CallingApp.PlotGo.Enable= 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Selection changed function: RegimGroup
        function RegimGroupSelectionChanged(app, event)
            selectedButton = app.RegimGroup.SelectedObject;
            if(selectedButton==app.Regim1)
                app.regim=9+1;
            end
            if(selectedButton==app.Regim2)
                app.regim=10+1;
                app.ParametrGroup.Visible='on';
            end
        end

        % Selection changed function: ParametrGroup
        function ParametrGroupSelectionChanged(app, event)
            selectedParametr = app.ParametrGroup.SelectedObject;
            if(selectedParametr==app.Speed)
                app.Parametr_index=13+1;
            end
            if(selectedParametr==app.Current)
                app.Parametr_index=14+1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Save = uibutton(app.UIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SavePushed, true);
            app.Save.Text = 'СОХРАНИТЬ';
            app.Cancel = uibutton(app.UIFigure, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Text = 'ОТМЕНА';
            app.RegimGroup = uibuttongroup(app.UIFigure);
            app.RegimGroup.SelectionChangedFcn = createCallbackFcn(app, @RegimGroupSelectionChanged, true);
            app.RegimGroup.Title = 'Выберите режим управления';
            app.Regim1 = uiradiobutton(app.RegimGroup);
            app.Regim1.Text = 'Ручное';
            app.Regim1.Value = true;
            app.Regim2 = uiradiobutton(app.RegimGroup);
            app.Regim2.Text = 'Подчиненное';
            app.ParametrGroup = uibuttongroup(app.UIFigure);
            app.ParametrGroup.SelectionChangedFcn = createCallbackFcn(app, @ParametrGroupSelectionChanged, true);
            app.ParametrGroup.Title = 'Выберите параметры управления';
            app.ParametrGroup.Visible = 'off';
            app.Speed = uiradiobutton(app.ParametrGroup);
            app.Speed.Text = 'Обороты';
            app.Speed.Value = true;
            app.Current = uiradiobutton(app.ParametrGroup);
            app.Current.Text = 'Ток';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptMotor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end